classdef CalculosApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DiabetesEditField       matlab.ui.control.EditField
        DiabetesEditFieldLabel  matlab.ui.control.Label
        HipertensionEditField   matlab.ui.control.EditField
        HipertensionLabel       matlab.ui.control.Label
        CalcularButton          matlab.ui.control.Button
        conhipertensionLabel    matlab.ui.control.Label
        condiabetesLabel        matlab.ui.control.Label
        EdadPromedioEditField   matlab.ui.control.NumericEditField
        PromedioLabel           matlab.ui.control.Label
        EdadpromedioLabel       matlab.ui.control.Label
        NumPersonasEditField    matlab.ui.control.NumericEditField
        TotalLabel              matlab.ui.control.Label
        TotaldepersonasLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
           conn = conectarPostgres();
    data = fetch(conn, 'SELECT * FROM personas');
    close(conn);

    % Calculos
    total = height(data);
    edadProm = mean(data.edad);
    porcentajeDiabetes = 100 * sum(data.tiene_diabetes) / total;
    porcentajeHipertension = 100 * sum(data.tiene_hipertension) / total;

    % Mostrar
    app.NumPersonasEditField.Value = total;
    app.EdadPromedioEditField.Value = round(edadProm, 2);
    app.DiabetesEditField.Value = sprintf('%.1f%%', porcentajeDiabetes);
    app.HipertensionEditField.Value = sprintf('%.1f%%', porcentajeHipertension);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TotaldepersonasLabel = uilabel(app.UIFigure);
            app.TotaldepersonasLabel.Text = 'Total de personas:';
            app.TotalLabel = uilabel(app.UIFigure);
            app.TotalLabel.Text = 'Total ';
            app.NumPersonasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumPersonasEditField.Editable = 'off';
            app.NumPersonasEditField.Value = 200;
            app.EdadpromedioLabel = uilabel(app.UIFigure);
            app.EdadpromedioLabel.Text = 'Edad promedio:';
            app.PromedioLabel = uilabel(app.UIFigure);
            app.PromedioLabel.Text = 'Edad';
            app.EdadPromedioEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdadPromedioEditField.Editable = 'off';
            app.EdadPromedioEditField.Value = 30;
            app.condiabetesLabel = uilabel(app.UIFigure);
            app.condiabetesLabel.Text = '% con diabetes:';
            app.conhipertensionLabel = uilabel(app.UIFigure);
            app.conhipertensionLabel.Text = '% con hipertension';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.HipertensionLabel = uilabel(app.UIFigure);
            app.HipertensionLabel.Text = 'Hipertension';
            app.HipertensionEditField = uieditfield(app.UIFigure, 'text');
            app.HipertensionEditField.Editable = 'off';
            app.HipertensionEditField.Value = '10';
            app.DiabetesEditFieldLabel = uilabel(app.UIFigure);
            app.DiabetesEditFieldLabel.Text = 'Diabetes';
            app.DiabetesEditField = uieditfield(app.UIFigure, 'text');
            app.DiabetesEditField.Value = '22.5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalculosApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end